classdef modelEllipseUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        vtGauge                matlab.ui.control.LinearGauge
        VNfitLabel_2           matlab.ui.control.Label
        vnGauge                matlab.ui.control.LinearGauge
        VNfitLabel             matlab.ui.control.Label
        horz2vertRatioSpinner  matlab.ui.control.Spinner
        Horz2vertRatioLabel    matlab.ui.control.Label
        ycenterSpinner         matlab.ui.control.Spinner
        ycenterSpinnerLabel    matlab.ui.control.Label
        xcenterSpinner         matlab.ui.control.Spinner
        xcenterSpinnerLabel    matlab.ui.control.Label
        vtAxes                 matlab.ui.control.UIAxes
        vnAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: horz2vertRatioSpinner, xcenterSpinner, 
        % ...and 1 other component
        function xcenterSpinnerValueChanged(app, event)
            value = app.xcenterSpinner.Value;
            
            %Input center of circle
            centerx = app.xcenterSpinner.Value;
            centery = app.ycenterSpinner.Value;
            x2yRadRatio = app.horz2vertRatioSpinner.Value;
            
            
            %Givens (these shouldn't change)
            vnPoints = [-1 -1];
            vtPoints = [1 -1];
            slopeZero = 0;
            
            
            numIterations = 10;
            varNames = {'vnIntX','vnIntY','vnDistFromON','vnOrthSlope','vnTheta','vtIntX','vtIntY','vtDistFromON','vtOrthSlope','vtTheta'};
            resultsTable = table('Size',[numIterations,10],'VariableTypes',{'double','double','double','double','double','double','double','double','double','double'}...
                ,'VariableNames',varNames);

            for i = 1:numIterations
            
                
                yrad = 0.2*i;
                xrad = yrad*x2yRadRatio;
                
                [C1,C2] = lineEllipse(xrad,yrad,[centerx;centery],vnPoints,[slopeZero,slopeZero]);
                C3 = [C1;C2];
                [M,I] = min(C3(:,1));
                vnInt = C3(I,:);
                vnOrthSlope = -(yrad^2*(vnInt(1)-centerx))/(xrad^2*(vnInt(2)-centery));
            
            
                [C1,C2] = lineEllipse(xrad,yrad,[centerx;centery],vtPoints,[slopeZero,slopeZero]);
                C3 = [C1;C2];
                [M,I] = max(C3(:,1));
                vtInt = C3(I,:);
                vtOrthSlope = -(yrad^2*(vtInt(1)-centerx))/(xrad^2*(vtInt(2)-centery));
                
                resultsTable.vnIntX(i) = vnInt(1);
                resultsTable.vnIntY(i) = vnInt(2);
                resultsTable.vnDistFromON(i) = sqrt(vnInt(1)^2+vnInt(2)^2);
                resultsTable.vnOrthSlope(i) = vnOrthSlope;
                resultsTable.vnTheta(i) = rad2deg(atan(1/vnOrthSlope));
            
                resultsTable.vtIntX(i) = vtInt(1);
                resultsTable.vtIntY(i) = vtInt(2);
                resultsTable.vtDistFromON(i) = sqrt(vtInt(1)^2+vtInt(2)^2);
                resultsTable.vtOrthSlope(i) = vtOrthSlope;
                resultsTable.vtTheta(i) = rad2deg(atan(1/vtOrthSlope));
            end


            resultsTable.vtTheta = resultsTable.vtTheta*-1;
            resultsTable.vnTheta = resultsTable.vnTheta*-1;
            resultsTable(1,:)=[];


            load dataOS_NRDRB2KO.mat
            vnData = dataOS(dataOS.distFromON > 0,:);
            vtData = dataOS(dataOS.distFromON < 0,:);
            vtData.distFromON = vtData.distFromON*-1;

            polFitData = 2;
            polFitModel = 2;
            
            
            scatter(app.vnAxes,vnData.distFromON,vnData.theta,'k.')
            hold(app.vnAxes,'on')
            p = polyfit(vnData.distFromON(~isnan(vnData.theta)),vnData.theta(~isnan(vnData.theta)),polFitData);
            vn_x1_data = linspace(0,2);
            vn_y1_data = polyval(p,vn_x1_data);
            plot(app.vnAxes,vn_x1_data,vn_y1_data,'k')
            scatter(app.vnAxes,resultsTable.vnDistFromON,resultsTable.vnTheta,'r')
            p = polyfit(resultsTable.vnDistFromON(~isnan(resultsTable.vnTheta)),resultsTable.vnTheta(~isnan(resultsTable.vnTheta)),polFitModel);
            vn_x1_model = linspace(0,2);
            vn_y1_model = polyval(p,vn_x1_model);
            plot(app.vnAxes,vn_x1_model,vn_y1_model,'r')
            ylim(app.vnAxes,[0 90])
            xlim(app.vnAxes,[0 2])
            hold(app.vnAxes,'off')

            app.vnGauge.Value = sum(abs(vn_y1_data-vn_y1_model));


            scatter(app.vtAxes, vtData.distFromON,vtData.theta,'k.')
            hold(app.vtAxes,'on')
            p = polyfit(vtData.distFromON(~isnan(vtData.theta)),vtData.theta(~isnan(vtData.theta)),polFitData);
            vt_x1_data = linspace(0,2);
            vt_y1_data = polyval(p,vt_x1_data);
            plot(app.vtAxes,vt_x1_data,vt_y1_data,'k')
            scatter(app.vtAxes,resultsTable.vtDistFromON,resultsTable.vtTheta,'r')
            p = polyfit(resultsTable.vtDistFromON(~isnan(resultsTable.vtTheta)),resultsTable.vtTheta(~isnan(resultsTable.vtTheta)),polFitModel);
            vt_x1_model = linspace(0,2);
            vt_y1_model = polyval(p,vt_x1_model);
            plot(app.vtAxes,vt_x1_model,vt_y1_model,'r')
            ylim(app.vtAxes,[-90 0])
            xlim(app.vtAxes,[0 2])
            hold(app.vtAxes,'off')

            app.vtGauge.Value = sum(abs(vt_y1_data-vt_y1_model));
            
        end

        % Value changed function: ycenterSpinner
        function ycenterSpinnerValueChanged(app, event)
            value = app.ycenterSpinner.Value;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.vnAxes = uiaxes(app.UIFigure);
            title(app.vnAxes, 'Title')
            xlabel(app.vnAxes, 'X')
            ylabel(app.vnAxes, 'Y')
            zlabel(app.vnAxes, 'Z')
            app.vtAxes = uiaxes(app.UIFigure);
            title(app.vtAxes, 'Title')
            xlabel(app.vtAxes, 'X')
            ylabel(app.vtAxes, 'Y')
            zlabel(app.vtAxes, 'Z')
            app.xcenterSpinnerLabel = uilabel(app.UIFigure);
            app.xcenterSpinnerLabel.Text = 'xcenter';
            app.xcenterSpinner = uispinner(app.UIFigure);
            app.xcenterSpinner.Step = 0.01;
            app.xcenterSpinner.ValueChangedFcn = createCallbackFcn(app, @xcenterSpinnerValueChanged, true);
            app.ycenterSpinnerLabel = uilabel(app.UIFigure);
            app.ycenterSpinnerLabel.Text = 'ycenter';
            app.ycenterSpinner = uispinner(app.UIFigure);
            app.ycenterSpinner.Step = 0.01;
            app.ycenterSpinner.ValueChangingFcn = createCallbackFcn(app, @xcenterSpinnerValueChanged, true);
            app.ycenterSpinner.ValueChangedFcn = createCallbackFcn(app, @ycenterSpinnerValueChanged, true);
            app.Horz2vertRatioLabel = uilabel(app.UIFigure);
            app.Horz2vertRatioLabel.Text = 'Horz2vertRatio';
            app.horz2vertRatioSpinner = uispinner(app.UIFigure);
            app.horz2vertRatioSpinner.Step = 0.01;
            app.horz2vertRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @xcenterSpinnerValueChanged, true);
            app.horz2vertRatioSpinner.Value = 1;
            app.VNfitLabel = uilabel(app.UIFigure);
            app.VNfitLabel.Text = 'VN fit';
            app.vnGauge = uigauge(app.UIFigure, 'linear');
            app.vnGauge.Limits = [0 1000];
            app.VNfitLabel_2 = uilabel(app.UIFigure);
            app.VNfitLabel_2.Text = 'VT fit';
            app.vtGauge = uigauge(app.UIFigure, 'linear');
            app.vtGauge.Limits = [0 1000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modelEllipseUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end